classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OperationsPanel           matlab.ui.container.Panel
        OCRSwitch                 matlab.ui.control.Switch
        OCRSwitchLabel            matlab.ui.control.Label
        PatternButtonGroup        matlab.ui.container.ButtonGroup
        xButton                   matlab.ui.control.RadioButton
        standardButton            matlab.ui.control.RadioButton
        DownloadimgButton         matlab.ui.control.Button
        GenerateimgButton         matlab.ui.control.Button
        ColumnSpinner             matlab.ui.control.Spinner
        ColumnSpinnerLabel        matlab.ui.control.Label
        RowSpinner                matlab.ui.control.Spinner
        RowSpinnerLabel           matlab.ui.control.Label
        DeleteimgButton           matlab.ui.control.Button
        UpdateimgButton           matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        PicturesListListBox       matlab.ui.control.ListBox
        PicturesListListBoxLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        imgs = struct('img', {}, 'path', {}, 'name', {});
        imgResult;
    end

    methods (Access = private)
        function updateImgList(app)
            app.PicturesListListBox.Items = {app.imgs.name};
        end

        function displaySelectImage(app)
            selectedName = app.PicturesListListBox.Value;
            if ~isempty(selectedName)
                % 查找对应的图片数据
                imgIndex = find(strcmp({app.imgs.name}, selectedName));
                if ~isempty(imgIndex)
                    img = app.imgs(imgIndex).img;
                    % 在ImageAxes中显示图片
                    imshow(img, 'Parent', app.UIAxes);
                end
            end

        end

        
        function img = useOcr(~,imgx)
            img = imgx;
            
            gray_img = rgb2gray(img);
            enhanced_img = imadjust(gray_img);

            
            edges = edge(enhanced_img, 'Canny', 0.3);  
            se = strel('rectangle', [5, 5]); 
            closed_edges = imclose(edges, se);

          
            filled_img = imfill(closed_edges, 'holes');
            [labels, num] = bwlabel(filled_img);
            stats = regionprops(labels, 'Area', 'BoundingBox');
            area_filter = [stats.Area] > 100;  
            stats = stats(area_filter);

            
            [~, idx] = max([stats.Area]);
            frame = stats(idx).BoundingBox;

           
            mask = true(size(img,1), size(img,2));
            x1 = ceil(frame(1));
            y1 = ceil(frame(2));
            x2 = x1 + frame(3) - 1;
            y2 = y1 + frame(4) - 1;
            mask(y1:y2, x1:x2) = false;

           
            ocr_result = ocr(img);

           
            text_positions = round([ocr_result.WordBoundingBoxes]);
            texts_to_restore = ocr_result.Words(any(mask(sub2ind(size(mask), text_positions(:,2), text_positions(:,1))), 2));
            text_locations = text_positions(any(mask(sub2ind(size(mask), text_positions(:,2), text_positions(:,1))), 2), :);

            img(repmat(mask, [1, 1, size(img, 3)])) = 255;

            
            for k = 1:length(texts_to_restore)
                position = text_locations(k, :);
                img = insertText(img, position(1:2), texts_to_restore{k}, ...
                    'FontSize', 50, 'BoxOpacity', 0, 'TextColor', 'black');
            end
           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateimgButton
        function updateImg(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', '图片文件 (*.jpg, *.jpeg, *.png, *.gif)'}, '选择图片');
            % 如果用户选择了图片文件
            if ischar(filename)
                img = imread(fullfile(pathname, filename));
                imgName = filename; % 使用文件名作为图片名称
                app.imgs(end + 1).img = img;
                app.imgs(end).path = fullfile(pathname, filename);
                app.imgs(end).name = imgName;
                app.updateImgList();
            else
                % 如果用户取消了选择，则显示提示信息
                msgbox('未选择图片文件。', '警告', 'warn');
            end
        end

        % Value changed function: PicturesListListBox
        function PicturesListListBoxValueChanged(app, event)
            app.displaySelectImage();
        end

        % Button pushed function: DeleteimgButton
        function deleteImage(app, event)
            selectedName = app.PicturesListListBox.Value;
            if ~isempty(selectedName)
                % 查找对应的图片数据
                imgIndex = find(strcmp({app.imgs.name}, selectedName));
                if ~isempty(imgIndex)
                    app.imgs(imgIndex) = [];
                    % 更新图片列表
                    app.updateImgList();
                    if isempty(app.PicturesListListBox.Items)
                        return;
                    end
                    app.PicturesListListBox.Value = app.PicturesListListBox.Items(end);
                    app.displaySelectImage();
                end
            else
                msgbox('未选择图片。', '警告', 'warn');
            end
        end

        % Button pushed function: GenerateimgButton
        function GenerateimgButtonPushed(app, event)
            pattern = app.xButton.Value;
            imgAll = app.imgs;
            n = length(imgAll);
            switch n
                case 2
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                case 3
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                case 4
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                case 5
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                case 6
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                case 7
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                case 8
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                    img8 = app.imgs(8).img;
                case 9
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                    img8 = app.imgs(8).img;
                    img9 = app.imgs(9).img;
                case 10
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                    img8 = app.imgs(8).img;
                    img9 = app.imgs(9).img;
                    img10 = app.imgs(10).img;
                case 11
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                    img8 = app.imgs(8).img;
                    img9 = app.imgs(9).img;
                    img10 = app.imgs(10).img;
                    img11 = app.imgs(11).img;
                case 12
                    img1 = app.imgs(1).img;
                    img2 = app.imgs(2).img;
                    img3 = app.imgs(3).img;
                    img4 = app.imgs(4).img;
                    img5 = app.imgs(5).img;
                    img6 = app.imgs(6).img;
                    img7 = app.imgs(7).img;
                    img8 = app.imgs(8).img;
                    img9 = app.imgs(9).img;
                    img10 = app.imgs(10).img;
                    img11 = app.imgs(11).img;
                    img12 = app.imgs(12).img;
            end
            
            % 获取每张图片的尺寸
            % for i = 1:n
            %     [height(i), width(i), ~] = size(imgi);
            % end

            %获取用户需要的排列方式
            row = app.RowSpinner.Value;
            column = app.ColumnSpinner.Value;

            % 压缩图片到原始尺寸的一半
            % imgCompressed = imresize(img, 0.5);
            % imwrite(imgCompressed, 'img_compressed.jpg');

            % 放大图片到原始尺寸的两倍
            % imgEnlarged = imresize(img, 2);
            % imwrite(imgEnlarged, 'img_enlarged.jpg');

            % 将图片调整为1500x1500像素
            % for i = 1:n
            % imgResized = imresize(img, [1500 1500]);
            % imwrite(imgResized, 'imgi');
            % end
            x = 1500;
            y = 1500;
            newsize = [x , y];
            newsize1 = [2*x, 2*y];
            newsize2 = [2*x, 3*y];
            newsize3 = [2*x, 4*y];
            switch n
                case 2
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                     if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                      
                    end
                case 3
                    if pattern
                        disp(pattern);
                        img1 = imresize(img1, newsize1);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                    else
                        img1 = imresize(img1, newsize);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                    end
                    if app.OCRSwitch.Value == "true"
                        disp(app.OCRSwitch.Value);
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        
                    end
                case 4
                    if pattern
                        img1 = imresize(img1, newsize2);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                    else
                        img1 = imresize(img1, newsize);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                    end
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        
                    end
                case 5
                    if pattern
                        img1 = imresize(img1, newsize3);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                        img5 = imresize(img5, newsize);
                    else
                        img1 = imresize(img1, newsize);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                        img5 = imresize(img5, newsize);
                    end
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        
                    end
                case 6
                    if pattern
                        img1 = imresize(img1, newsize1);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                        img5 = imresize(img5, newsize);
                        img6 = imresize(img6, newsize);
                    else
                        img1 = imresize(img1, newsize);
                        img2 = imresize(img2, newsize);
                        img3 = imresize(img3, newsize);
                        img4 = imresize(img4, newsize);
                        img5 = imresize(img5, newsize);
                        img6 = imresize(img6, newsize);
                    end
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                       
                    end
                case 7
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                       
                    end
                case 8
                    img1 = app.imgs(1).img;
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    img8 = imresize(img8, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                        img8 = useOcr(app,img8);
                       
                    end
                case 9
                    img1 = app.imgs(1).img;
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    img8 = imresize(img8, newsize);
                    img9 = imresize(img9, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                        img8 = useOcr(app,img8);
                        img9 = useOcr(app,img9);
                      
                    end
                case 10
                    img1 = app.imgs(1).img;
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    img8 = imresize(img8, newsize);
                    img9 = imresize(img9, newsize);
                    img10 = imresize(img10, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                        img8 = useOcr(app,img8);
                        img9 = useOcr(app,img9);
                        img10 = useOcr(app,img10);
                       
                    end
                case 11
                    img1 = app.imgs(1).img;
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    img8 = imresize(img8, newsize);
                    img9 = imresize(img9, newsize);
                    img10 = imresize(img10, newsize);
                    img11 = imresize(img11, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                        img8 = useOcr(app,img8);
                        img9 = useOcr(app,img9);
                        img10 = useOcr(app,img10);
                        img11 = useOcr(app,img11);
                        
                    end
                case 12
                    img1 = app.imgs(1).img;
                    img1 = imresize(img1, newsize);
                    img2 = imresize(img2, newsize);
                    img3 = imresize(img3, newsize);
                    img4 = imresize(img4, newsize);
                    img5 = imresize(img5, newsize);
                    img6 = imresize(img6, newsize);
                    img7 = imresize(img7, newsize);
                    img8 = imresize(img8, newsize);
                    img9 = imresize(img9, newsize);
                    img10 = imresize(img10, newsize);
                    img11 = imresize(img11, newsize);
                    img12 = imresize(img12, newsize);
                    if app.OCRSwitch.Value == "true"
                        img1 = useOcr(app,img1);
                        img2 = useOcr(app,img2);
                        img3 = useOcr(app,img3);
                        img4 = useOcr(app,img4);
                        img5 = useOcr(app,img5);
                        img6 = useOcr(app,img6);
                        img7 = useOcr(app,img7);
                        img8 = useOcr(app,img8);
                        img9 = useOcr(app,img9);
                        img10 = useOcr(app,img10);
                        img11 = useOcr(app,img11);
                        img12 = useOcr(app,img12);
                    end
            end


            % 创建一个空矩阵用于存储组合后的图片
            combinedImage = uint8(255 * ones(x * row, y * column, 3));


            % 将每张图片放置在组合图片的正确位置
            switch row
                case 1
                    switch column
                        case 2
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                        case 3
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                            combinedImage(1:x, 1+2*y:3*y, :) = img3;
                        case 4
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                            combinedImage(1:x, 1+2*y:3*y, :) = img3;
                            combinedImage(1:x, 1+3*y:4*y, :) = img4;
                        case 5
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                            combinedImage(1:x, 1+2*y:3*y, :) = img3;
                            combinedImage(1:x, 1+3*y:4*y, :) = img4;
                            combinedImage(1:x, 1+4*y:5*y, :) = img5;
                    end
                case 2
                    switch column
                        case 1
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1+x:2*x, 1:y, :) = img2;
                        case 2
                            if n==3
                                combinedImage(1:x, 1+0.5*y:1.5*y, :) = img1;
                                combinedImage(1+x:2*x, 1:y, :) = img2;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img3;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1:x, 1+y:2*y, :) = img2;
                                combinedImage(1+x:2*x, 1:y, :) = img3;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img4;
                            end
                        case 3
                            if n == 5
                                combinedImage(1+x:2*x, 1:y, :) = img1;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img2;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img3;
                                combinedImage(1:x, 1+y/2:1.5*y, :) = img4;
                                combinedImage(1:x, 1+1.5*y:2.5*y, :) = img5;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1:x, 1+y:2*y, :) = img2;
                                combinedImage(1:x, 1+2*y:3*y, :) = img3;
                                combinedImage(1+x:2*x, 1:y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img6;
                            end
                        case 4
                            if n ==7
                                combinedImage(1:x, 1+0.5*y:1.5*y, :) = img1;
                                combinedImage(1:x, 1+1.5*y:2.5*y, :) = img2;
                                combinedImage(1:x, 1+2.5*y:3.5*y, :) = img3;
                                combinedImage(1+x:2*x, 1:y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img6;
                                combinedImage(1+x:2*x, 1+3*y:4*y, :) = img7;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1:x, 1+y:2*y, :) = img2;
                                combinedImage(1:x, 1+2*y:3*y, :) = img3;
                                combinedImage(1:x, 1+3*y:4*y, :) = img4;
                                combinedImage(1+x:2*x, 1:y, :) = img5;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img6;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img7;
                                combinedImage(1+x:2*x, 1+3*y:4*y, :) = img8;
                            end
                        case 5
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                            combinedImage(1:x, 1+2*y:3*y, :) = img3;
                            combinedImage(1:x, 1+3*y:4*y, :) = img4;
                            combinedImage(1:x, 1+4*y:5*y, :) = img5;
                            combinedImage(1+x:2*x, 1:y, :) = img6;
                            combinedImage(1+x:2*x, 1+y:2*y, :) = img7;
                            combinedImage(1+x:2*x, 1+2*y:3*y, :) = img8;
                            combinedImage(1+x:2*x, 1+3*y:4*y, :) = img9;
                            combinedImage(1+x:2*x, 1+4*y:5*y, :) = img10;
                        case 6
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1:x, 1+y:2*y, :) = img2;
                            combinedImage(1:x, 1+2*y:3*y, :) = img3;
                            combinedImage(1:x, 1+3*y:4*y, :) = img4;
                            combinedImage(1:x, 1+4*y:5*y, :) = img5;
                            combinedImage(1:x, 1+5*y:6*y, :) = img6;
                            combinedImage(1+x:2*x, 1:y, :) = img7;
                            combinedImage(1+x:2*x, 1+y:2*y, :) = img8;
                            combinedImage(1+x:2*x, 1+2*y:3*y, :) = img9;
                            combinedImage(1+x:2*x, 1+3*y:4*y, :) = img10;
                            combinedImage(1+x:2*x, 1+4*y:5*y, :) = img11;
                            combinedImage(1+x:2*x, 1+5*y:6*y, :) = img12;
                    end
                case 3
                    switch column
                        case 1
                            combinedImage(1:x, 1:y, :) = img1;
                            combinedImage(1+x:2*x, 1:y, :) = img2;
                            combinedImage(1+2*x:3*x, 1:y, :) = img3;
                        case 2
                            if n == 3
                                combinedImage(1:2*x, 1:2*y, :) = img1;
                                combinedImage(1+2*x:3*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img3;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1+x:2*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1:y, :) = img3;
                                combinedImage(1:x, 1+y:2*y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img6;
                            end
                        case 3
                            if n ==4
                                combinedImage(1:2*x, 1:3*y, :) = img1;
                                combinedImage(1+2*x:3*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img3;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img4;
                            elseif n==6
                                combinedImage(1:2*x, 1:2*y, :) = img1;
                                combinedImage(1+2*x:3*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img3;
                                combinedImage(1:x, 1+2*y:3*y, :) = img4;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img5;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img6;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1+x:2*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1:y, :) = img3;
                                combinedImage(1:x, 1+y:2*y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img6;
                                combinedImage(1:x, 1+2*y:3*y, :) = img7;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img8;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img9;
                            end
                        case 4
                            if n==11
                                combinedImage(1:x, 1+0.5*y:1.5*y, :) = img1;
                                combinedImage(1+x:2*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1:y, :) = img3;
                                combinedImage(1:x, 1+1.5*y:2.5*y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img6;
                                combinedImage(1:x, 1+2.5*y:3.5*y, :) = img7;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img8;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img9;
                                combinedImage(1+x:2*x, 1+3*y:4*y, :) = img10;
                                combinedImage(1+2*x:3*x, 1+3*y:4*y, :) = img11;
                            elseif n ==5
                                combinedImage(1:2*x, 1:4*y, :) = img1;
                                combinedImage(1+2*x:3*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img3;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img4;
                                combinedImage(1+2*x:3*x, 1+3*y:4*y, :) = img5;
                            else
                                combinedImage(1:x, 1:y, :) = img1;
                                combinedImage(1+x:2*x, 1:y, :) = img2;
                                combinedImage(1+2*x:3*x, 1:y, :) = img3;
                                combinedImage(1:x, 1+y:2*y, :) = img4;
                                combinedImage(1+x:2*x, 1+y:2*y, :) = img5;
                                combinedImage(1+2*x:3*x, 1+y:2*y, :) = img6;
                                combinedImage(1:x, 1+2*y:3*y, :) = img7;
                                combinedImage(1+x:2*x, 1+2*y:3*y, :) = img8;
                                combinedImage(1+2*x:3*x, 1+2*y:3*y, :) = img9;
                                combinedImage(1:x, 1+3*y:4*y, :) = img10;
                                combinedImage(1+x:2*x, 1+3*y:4*y, :) = img11;
                                combinedImage(1+2*x:3*x, 1+3*y:4*y, :) = img12;
                            end
                    end
            end


            % 显示组合后的图片
            imshow(combinedImage);
            app.imgResult = combinedImage;
        end

        % Button pushed function: DownloadimgButton
        function downLoadImg(app, event)
            imwrite(app.imgResult, 'combined_image.jpg');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Img Show')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PicturesListListBoxLabel = uilabel(app.Panel);
            app.PicturesListListBoxLabel.Text = 'Pictures List';
            app.PicturesListListBox = uilistbox(app.Panel);
            app.PicturesListListBox.Items = {''};
            app.PicturesListListBox.ValueChangedFcn = createCallbackFcn(app, @PicturesListListBoxValueChanged, true);
            app.PicturesListListBox.Value = {};
            app.OperationsPanel = uipanel(app.UIFigure);
            app.OperationsPanel.Title = 'Operations';
            app.UpdateimgButton = uibutton(app.OperationsPanel, 'push');
            app.UpdateimgButton.ButtonPushedFcn = createCallbackFcn(app, @updateImg, true);
            app.UpdateimgButton.Text = 'Update img';
            app.DeleteimgButton = uibutton(app.OperationsPanel, 'push');
            app.DeleteimgButton.ButtonPushedFcn = createCallbackFcn(app, @deleteImage, true);
            app.DeleteimgButton.Text = 'Delete img';
            app.RowSpinnerLabel = uilabel(app.OperationsPanel);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinner = uispinner(app.OperationsPanel);
            app.RowSpinner.Limits = [1 Inf];
            app.RowSpinner.Value = 1;
            app.ColumnSpinnerLabel = uilabel(app.OperationsPanel);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.OperationsPanel);
            app.ColumnSpinner.Limits = [1 Inf];
            app.ColumnSpinner.Value = 1;
            app.GenerateimgButton = uibutton(app.OperationsPanel, 'push');
            app.GenerateimgButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateimgButtonPushed, true);
            app.GenerateimgButton.Text = 'Generate img';
            app.DownloadimgButton = uibutton(app.OperationsPanel, 'push');
            app.DownloadimgButton.ButtonPushedFcn = createCallbackFcn(app, @downLoadImg, true);
            app.DownloadimgButton.Text = 'Download img';
            app.PatternButtonGroup = uibuttongroup(app.OperationsPanel);
            app.PatternButtonGroup.Title = 'Pattern';
            app.standardButton = uiradiobutton(app.PatternButtonGroup);
            app.standardButton.Text = 'standard';
            app.standardButton.Value = true;
            app.xButton = uiradiobutton(app.PatternButtonGroup);
            app.xButton.Text = '1 + x';
            app.OCRSwitchLabel = uilabel(app.OperationsPanel);
            app.OCRSwitchLabel.Text = 'OCR';
            app.OCRSwitch = uiswitch(app.OperationsPanel, 'slider');
            app.OCRSwitch.Items = {'false', 'true'};
            app.OCRSwitch.Value = 'false';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end